classdef thin_film_simulator < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		CopyModeltoDataButton           matlab.ui.control.Button
		UnloadTransmissionButton        matlab.ui.control.Button
		AutoPlotButton                  matlab.ui.control.StateButton
		LoadspolTransmissionButton      matlab.ui.control.Button
		LoadppolTransmissionButton      matlab.ui.control.Button
		ConversionsPanel                matlab.ui.container.Panel
		cm1EditField                    matlab.ui.control.NumericEditField
		cm1EditFieldLabel               matlab.ui.control.Label
		nmEditField                     matlab.ui.control.NumericEditField
		nmEditFieldLabel                matlab.ui.control.Label
		InternalReflectionsButtonGroup  matlab.ui.container.ButtonGroup
		MultipleButton                  matlab.ui.control.ToggleButton
		NoneButton                      matlab.ui.control.ToggleButton
		PlotUnitsDropDown               matlab.ui.control.DropDown
		PlotUnitsDropDownLabel          matlab.ui.control.Label
		MaterialFactorsTable            matlab.ui.control.Table
		RemoveTargetButton              matlab.ui.control.Button
		AddTargetButton                 matlab.ui.control.Button
		FitTargetsTable                 matlab.ui.control.Table
		FittingPanel                    matlab.ui.container.Panel
		StopFittingButton               matlab.ui.control.StateButton
		StartFittingButton              matlab.ui.control.Button
		UpdatePlotButton                matlab.ui.control.Button
		ClearFeedbackButton             matlab.ui.control.Button
		FeedbackBox                     matlab.ui.control.EditField
		LoadAxisButton                  matlab.ui.control.Button
		LoadDesignButton                matlab.ui.control.Button
		SaveDesignButton                matlab.ui.control.Button
		RemoveLayerButton               matlab.ui.control.Button
		AddLayerButton                  matlab.ui.control.Button
		AOIdegreesSpinner               matlab.ui.control.Spinner
		AOIdegreesSpinnerLabel          matlab.ui.control.Label
		StackDesignTable                matlab.ui.control.Table
	end

    
    properties (Access = private)
        AOI; % Angle of Incidence (degrees)
        orig_mat; % orignal data for materials
		mat; % interpolated data for materials
        wl; % wavelength
		last_path = pwd;
		front_layers % indicies of front stack layers
		back_layers % indicies of back stack layers
		selected_layers % indicies of front and back layers selected
		front_layers_selected % indicies of front stack layers selected
		back_layers_selected % indicies of back stack layers selected
		inc_selected % incident layer selected? (0 or 1)
        sub_selected % substrate layer selected? (0 or 1)
        exit_selected % exit layer selected? (0 or 1)
        targets_selected % indicies of targets selected
        fig % plots figure
        data_s % s-pol transmission data
		data_p % p-pol transmission data
    end
    
    methods (Access = public)
        
		function loadMaterials(app)
			try
				% Air
					app.orig_mat.Air.wl = (300:100:10000)';
					app.orig_mat.Air.n = ones(size(app.orig_mat.Air.wl));
				% Ag
					tbl = table2array(readtable('Refractive Index Data\Ag - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Ag';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Al
					tbl = table2array(readtable('Refractive Index Data\Al - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Al';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Au
					tbl = table2array(readtable('Refractive Index Data\Au - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Au';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Cr
					tbl = table2array(readtable('Refractive Index Data\Cr - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Cr';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Cu
					tbl = table2array(readtable('Refractive Index Data\Cu - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Cu';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Fe
					tbl = table2array(readtable('Refractive Index Data\Fe - Ordal.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Fe';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Mo
					tbl = table2array(readtable('Refractive Index Data\Mo - Ordal.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Mo';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Ni
					tbl = table2array(readtable('Refractive Index Data\Ni - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Ni';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Pb
					tbl = table2array(readtable('Refractive Index Data\Pb - Ordal.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Pb';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Pd
					tbl = table2array(readtable('Refractive Index Data\Pd - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Pd';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Pt
					tbl = table2array(readtable('Refractive Index Data\Pt - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Pt';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Sn
					tbl = table2array(readtable('Refractive Index Data\Sn - Golovashkin (293K).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Sn';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Ta
					tbl = table2array(readtable('Refractive Index Data\Ta - Ordal.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Ta';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Ti
					tbl = table2array(readtable('Refractive Index Data\Ti - Rakic (LD model).csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Ti';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Al2O3
					tbl = table2array(readtable('Refractive Index Data\Al2O3 - Boidin.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.Al2O3.wl = tbl(:,1)*1000;
					app.orig_mat.Al2O3.n = tbl(:,2);
				% AlN
					tbl = table2array(readtable('Refractive Index Data\AlN - Pastrnak-o.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.AlN.wl = tbl(:,1)*1000;
					app.orig_mat.AlN.n = tbl(:,2);
				% CaF2
					tbl = table2array(readtable('Refractive Index Data\CaF2 - Malitson.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.CaF2.wl = tbl(:,1)*1000;
					app.orig_mat.CaF2.n = tbl(:,2);
				% Ge
					tbl = table2array(readtable('Refractive Index Data\Ge - Amotchkina.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Ge';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% MgF2
					tbl = table2array(readtable('Refractive Index Data\MgF2 - Dodge-o.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.MgF2.wl = tbl(:,1)*1000;
					app.orig_mat.MgF2.n = tbl(:,2);
				% Si
					tbl = table2array(readtable('Refractive Index Data\Si - Shkondin and Schinke merge.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'Si';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% Si3N4
					tbl = table2array(readtable('Refractive Index Data\Si3N4 - Luke.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.Si3N4.wl = tbl(:,1)*1000;
					app.orig_mat.Si3N4.n = tbl(:,2);
				% SiO
					tbl = table2array(readtable('Refractive Index Data\SiO - Hass.csv','ReadRowNames',false,'PreserveVariableNames',true));
					name = 'SiO';
					if rem(size(tbl,1),2)
						if all(isnan(tbl(ceil(size(tbl,1)/2),:)))
							N = floor(size(tbl,1)/2);
							if all(tbl(1:N,1) == tbl((N+2):(2*N+1),1))
								app.orig_mat.(name).wl = tbl(1:N,1)*1000;
								app.orig_mat.(name).n = tbl(1:N,2) + 1i*tbl((N+2):(2*N+1),2);
							else
								app.orig_mat.(name).wl = tbl(:,1)*1000;
								app.orig_mat.(name).n = tbl(:,2);
							end
						else
							app.orig_mat.(name).wl = tbl(:,1)*1000;
							app.orig_mat.(name).n = tbl(:,2);
						end
					else
						app.orig_mat.(name).wl = tbl(:,1)*1000;
						app.orig_mat.(name).n = tbl(:,2);
					end
				% SiO2
					tbl = table2array(readtable('Refractive Index Data\SiO2 - Malitson.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.SiO2.wl = tbl(:,1)*1000;
					app.orig_mat.SiO2.n = tbl(:,2);
				% TiO2
					tbl = table2array(readtable('Refractive Index Data\TiO2 - Siefke.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.TiO2.wl = tbl(:,1)*1000;
					app.orig_mat.TiO2.n = tbl(:,2);
				% ZnSe
					tbl = table2array(readtable('Refractive Index Data\ZnSe - Connolly.csv','ReadRowNames',false,'PreserveVariableNames',true));
					app.orig_mat.ZnSe.wl = tbl(:,1)*1000;
					app.orig_mat.ZnSe.n = tbl(:,2);
			catch exception
				update_feedback_box(app,exception);
			end
		end
		
		function initDesignTable(app)
			try
				initData = {'Air' 1e6 0 4630 0.25;'CaF2' 1e6 0 4630 0.25;'Air' 1e6 0 4630 0.25};
				set(app.StackDesignTable,'ColumnFormat',({cellstr(fieldnames(app.orig_mat)'),[],'logical',[],[]}),'Data',initData,'RowName',{'incident','substrate','exit'});
			catch exception
				update_feedback_box(app,exception);
			end
        end
		function initFitTargetTable(app)
			try
				initData = {'Rs',99.9,1850,2000,1;'Rs_GDD',0,1850,2000,1};
				prop = {'Rs','Rp','Ts','Tp','Rs_GDD','Rp_GDD','Ts_GDD','Tp_GDD'};
				set(app.FitTargetsTable,'ColumnFormat',{prop,[],[],[]},'Data',initData,'RowName',{'1','2'});
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
        function update_feedback_box(app,feedback)
			if isprop(app,'FeedbackBox')
				switch class(feedback)
					case 'MException'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = ['MATLAB ERROR: ',getReport(feedback)];
						else
							app.FeedbackBox.Value = string([app.FeedbackBox.Value,newline,['MATLAB ERROR: ',getReport(feedback)]]);
						end
					case 'string'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = feedback;
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);feedback];
						end
					case 'char'
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = feedback;
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);feedback];
						end
					otherwise
						if strcmp(app.FeedbackBox.Value,'')
							app.FeedbackBox.Value = 'Invalid data type for ''feedback''';
						else
							app.FeedbackBox.Value = [string(app.FeedbackBox.Value);'Invalid data type for ''feedback'''];
						end
				end
			end
		end
		
		function updateMaterials(app)
			try
				if ~isempty(app.wl)
					if ~isempty(app.mat)
						fn = fieldnames(app.mat);
						app.mat = rmfield(app.mat,fn);
					end
					fn = fieldnames(app.orig_mat);
					app.mat = app.orig_mat;
					for i=1:length(fn)
						app.mat.(fn{i}).wl = app.wl;
						app.mat.(fn{i}).n = spline(app.orig_mat.(fn{i}).wl,real(app.orig_mat.(fn{i}).n),app.wl) + 1i*spline(app.orig_mat.(fn{i}).wl,imag(app.orig_mat.(fn{i}).n),app.wl);
						app.mat.(fn{i}).index_fctr = app.MaterialFactorsTable.Data{i,2};
						app.mat.(fn{i}).thickness_fctr = app.MaterialFactorsTable.Data{i,3};
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function updatePlot(app)
			try
				wl_index_arr = 1:1:numel(app.wl);
				mat_fn = app.StackDesignTable.Data(:,1);
				d = cell2mat(app.StackDesignTable.Data(:,2));
				[Ts,Tp,Rs,Rp,phi_Rs,phi_Rp,phi_Ts,phi_Tp,GDD_Rs,GDD_Rp,GDD_Ts,GDD_Tp] = comp_sub_and_stacks(app,d,mat_fn,wl_index_arr);
				% x axis units
					switch app.PlotUnitsDropDown.Value
						case 'cm-1'
							x_label = '\nu (cm^{-1})';
							x_axis = (1e7)./(app.wl);
						case 'nm'
							x_label = '\lambda (nm)';
							x_axis = app.wl;
					end
				% check for figure; make one if it doesn't exist
					replot_flag = 1;
					if isempty(findobj(app.fig))
						app.fig = figure;
						app.fig.Position = [20,50,1400,700];
						tiledlayout(app.fig,3,2,"Padding","compact","TileSpacing","compact");
						replot_flag = 0;
					end
					t = app.fig.Children(1);
				% plot transmission on left axis
					ax = nexttile(t,1);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					lines = plot(ax,x_axis,Ts*100,'k-',x_axis,Tp*100,'k--');
					lines(1).DisplayName = 'T_s';
					lines(2).DisplayName = 'T_p';
					if ~isempty(app.data_p)
						hold(ax,'on')
						switch  app.PlotUnitsDropDown.Value
							case 'cm-1'
								x_axis_2 = (1e7)./app.data_p(:,1);
							case 'nm'
								x_axis_2 = (app.data_p(:,1));
						end
						line = plot(ax,x_axis_2,app.data_p(:,2)*100,'r--');
						line.DisplayName = 'T_p Data';
						hold(ax,'off')
					end
					if ~isempty(app.data_s)
						hold(ax,'on')
						switch  app.PlotUnitsDropDown.Value
							case 'cm-1'
								x_axis_2 = (1e7)./(app.data_s(:,1));
							case 'nm'
								x_axis_2 = app.data_s(:,1);
						end
						line = plot(ax,x_axis_2,app.data_s(:,2)*100,'r-');
						line.DisplayName = 'T_s Data';
						hold(ax,'off')
					end
					legend(ax);
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					else
						if ax.YLim(1) < 0 
							ax.YLim(1) = 0;
						end
						if ax.YLim(2) > 100 
							ax.YLim(2) = 100;
						end
					end
					ylabel(ax,'Transmission (%)');xlabel(ax,x_label);
					title(ax,'Transmission');
				% plot reflection on right axis
					ax = nexttile(t,2);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					plot(ax,x_axis,Rs*100,'k-',x_axis,Rp*100,'k--');
					ylabel(ax,'Reflection (%)');xlabel(ax,x_label);
					legend(ax,'R_s','R_p');title(ax,'Reflection');
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					else
						if ax.YLim(1) < 0 
							ax.YLim(1) = 0;
						end
						if ax.YLim(2) > 100 
							ax.YLim(2) = 100;
						end
					end
				% plot phase of transmission
					ax = nexttile(t,3);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					plot(ax,x_axis,phi_Ts,'k-',x_axis,phi_Tp,'k--');
					ylabel(ax,'\phi (rad)');xlabel(ax,x_label);
					legend(ax,'T_s \phi','T_p \phi');title(ax,'Phase');
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					end
				% plot phase of reflection
					ax = nexttile(t,4);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					plot(ax,x_axis,phi_Rs,'k-',x_axis,phi_Rp,'k--');
					ylabel(ax,'\phi (rad)');xlabel(ax,x_label);
					legend(ax,'R_s \phi','R_p \phi');title(ax,'Phase');
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					end
				% plot GDD of transmission
					ax = nexttile(t,5);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					plot(ax,x_axis,GDD_Ts,'k-',x_axis,GDD_Tp,'k--');
					ylabel(ax,'GDD (fs^2)');xlabel(ax,x_label);
					legend(ax,'T_s GDD','T_p GDD');title(ax,'GDD');
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					end
				% plot GDD of reflection
					ax = nexttile(t,6);
					y_lim = ax.YLim;
					x_lim = ax.XLim;
					plot(ax,x_axis,GDD_Rs,'k-',x_axis,GDD_Rp,'k--');
					ylabel(ax,'GDD (fs^2)');xlabel(ax,x_label);
					legend(ax,'R_s GDD','R_p GDD');title(ax,'GDD');
					if replot_flag
						ax.YLim = y_lim;
						ax.XLim = x_lim;
					end
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function checkStackDesignTable(app)
			try
				for i=1:size(app.StackDesignTable.Data,1)
					if app.StackDesignTable.Data{i,3}
						n_inc = spline(app.orig_mat.(app.StackDesignTable.Data{1,1}).wl,real(app.orig_mat.(app.StackDesignTable.Data{1,1}).n),app.StackDesignTable.Data{i,4});
						n = spline(app.orig_mat.(app.StackDesignTable.Data{i,1}).wl,real(app.orig_mat.(app.StackDesignTable.Data{i,1}).n),app.StackDesignTable.Data{i,4});
						a = asin( n_inc./n * sin(app.AOI/180*pi) );
						app.StackDesignTable.Data{i,2} = app.StackDesignTable.Data{i,4}*app.StackDesignTable.Data{i,5}/n/cos(a);
					end
				end
				app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
			catch exception
				update_feedback_box(app,exception);
			end
		end
        
        function stack = thinFilmStack(app,d,mat_fn,indx_arr,mat_fn_init)
			try
				Nd = length(d);
				Nwl = numel(indx_arr);
				M = @(phi,nu) [cos(phi),1i*sin(phi)./nu;1i*sin(phi).*nu,cos(phi)];
				n = zeros(1,Nd);
				phi = zeros(1,Nd);
				nu_s = zeros(1,Nd);
				nu_p = zeros(1,Nd);
				stack.Rs = zeros(1,Nwl);
				stack.Ts = zeros(1,Nwl);
				stack.Rp = zeros(1,Nwl);
				stack.Tp = zeros(1,Nwl);
				stack.phi_Rs = zeros(1,Nwl);
				stack.phi_Ts = zeros(1,Nwl);
				stack.phi_Rp = zeros(1,Nwl);
				stack.phi_Tp = zeros(1,Nwl);
				for k=1:Nwl
					n_init = app.mat.(mat_fn_init).n(indx_arr(k))*app.mat.(mat_fn_init).index_fctr;
					for m=1:Nd
						n(m) = app.mat.(mat_fn{m}).n(indx_arr(k))*app.mat.(mat_fn{m}).index_fctr;
						d_mod(m) = d(m)*app.mat.(mat_fn{m}).thickness_fctr;
					end
					for i=1:Nd % layer 1 (incident medium) and layer Nd (exit medium) of M_s and M_p won't be used but it's convenient to have them there for indexing the intermediate layers
						ncos(i) = sqrt(real(n(i))^2-imag(n(i))^2-real(n_init)^2*sin(app.AOI/180*pi)^2-2*1i*real(n(i))*imag(n(i)));
						phi(i) = (2*pi/app.wl(indx_arr(k)))*d_mod(i)*ncos(i);
						nu_s(i) = ncos(i);
						nu_p(i) = (real(n(i))-1i*imag(n(i)))^2/ncos(i);
						if i==1
							Ms = eye(2);
							Mp = eye(2);
						elseif i<Nd
							Ms = Ms*M(phi(i),nu_s(i)); % bottom of page 46
							Mp = Mp*M(phi(i),nu_p(i)); % bottom of page 46
						end
					end
					%% [B] = [M][1 ]
					%% [C]      [nu]
					% s-pol
						B_s = Ms(1,:) * [ 1 ; nu_s(Nd) ];
						C_s = Ms(2,:) * [ 1 ; nu_s(Nd) ];
						r_s = (nu_s(1)*B_s-C_s) / (nu_s(1)*B_s+C_s);
						t_s = 2*nu_s(1) / (nu_s(1)*B_s+C_s);
						stack.Rs(k) = abs( r_s )^2;
						stack.Ts(k) = real( nu_s(Nd) )/real( nu_s(1) ) * abs( t_s )^2;
% 						stack.Rs(k) = abs( (nu_s(1)*B_s-C_s) / (nu_s(1)*B_s+C_s) )^2;
% 						stack.Ts(k) = 4*nu_s(1)*real(nu_s(Nd)) / abs(nu_s(1)*B_s+C_s)^2;
						stack.phi_Rs(k) = atan2(imag(nu_s(1)*((C_s')*B_s-(B_s')*C_s)),real(nu_s(1)^2*(B_s')*B_s-C_s'*C_s));
						stack.phi_Ts(k) = atan2(-imag(nu_s(1)*B_s+C_s),real(nu_s(1)*B_s+C_s));
					% p-pol
						B_p = Mp(1,:) * [ 1 ; nu_p(Nd) ];
						C_p = Mp(2,:) * [ 1 ; nu_p(Nd) ];
						r_p = (nu_p(1)*B_p-C_p) / (nu_p(1)*B_p+C_p);
						t_p = 2*nu_p(1) / (nu_p(1)*B_p+C_p);
						stack.Rp(k) = abs( r_p )^2;
						stack.Tp(k) = real( nu_p(Nd) )/real( nu_p(1) ) * abs( t_p )^2;
% 						stack.Rp(k) = abs( (nu_p(1)*B_p-C_p) / (nu_p(1)*B_p+C_p) )^2;
% 						stack.Tp(k) = 4*nu_p(1)*real(nu_p(Nd)) / abs(nu_p(1)*B_p+C_p)^2;
						stack.phi_Rp(k) = atan2(imag(nu_p(1)*((C_p')*B_p-(B_p')*C_p)),real(nu_p(1)^2*(B_p')*B_p-C_p'*C_p));
						stack.phi_Tp(k) = atan2(-imag(nu_p(1)*B_p+C_p),real(nu_p(1)*B_p+C_p));
				end
				% unwrap phase
					stack.phi_Rs = unwrap(stack.phi_Rs);
					stack.phi_Ts = unwrap(stack.phi_Ts);
					stack.phi_Rp = unwrap(stack.phi_Rp);
					stack.phi_Tp = unwrap(stack.phi_Tp);
				% GDD for Rp
					d1 = gradient(stack.phi_Rs,app.wl(2)-app.wl(1));
					d2 = 4*del2(stack.phi_Rs,app.wl(2)-app.wl(1));
					stack.GDD_Rs = -(app.wl(indx_arr)').^4.*d2/(4*pi^2*300^2)-(app.wl(indx_arr)').^3.*d1/(2*pi^2*300^2);
				% GDD for Rs 
					d1 = gradient(stack.phi_Rp,app.wl(2)-app.wl(1));
					d2 = 4*del2(stack.phi_Rp,app.wl(2)-app.wl(1));
					stack.GDD_Rp = -(app.wl(indx_arr)').^4.*d2/(4*pi^2*300^2)-(app.wl(indx_arr)').^3.*d1/(2*pi^2*300^2);
				% GDD for Tp
					d1 = gradient(stack.phi_Ts,app.wl(2)-app.wl(1));
					d2 = 4*del2(stack.phi_Ts,app.wl(2)-app.wl(1));
					stack.GDD_Ts = -(app.wl(indx_arr)').^4.*d2/(4*pi^2*300^2)-(app.wl(indx_arr)').^3.*d1/(2*pi^2*300^2);
				% GDD for Ts 
					d1 = gradient(stack.phi_Tp,app.wl(2)-app.wl(1));
					d2 = 4*del2(stack.phi_Tp,app.wl(2)-app.wl(1));
					stack.GDD_Tp = -(app.wl(indx_arr)').^4.*d2/(4*pi^2*300^2)-(app.wl(indx_arr)').^3.*d1/(2*pi^2*300^2);
			catch exception
				update_feedback_box(app,exception);
			end
        end
		
		function [obj_x_arr,obj_y_arr] = makeFitTarget(app,prop_struct)
			fn = fieldnames(prop_struct);
			obj_y_arr = [];
			obj_x_arr = [];
			for i=1:length(fn)
				wl_arr = (prop_struct.(fn{i}).indx_arr(1)):1:(prop_struct.(fn{i}).indx_arr(2));
				obj_x_arr = [obj_x_arr;app.wl(wl_arr)];
				obj_y_arr = [obj_y_arr; prop_struct.(fn{i}).val * ones(numel(wl_arr),1) * prop_struct.(fn{i}).weight];
			end
        end
		
		function obj_y_arr = obj_fun(app,d_init_all,d_fit,fit_indx,mat_fn,x,prop_struct)
			d = d_init_all;
			d(fit_indx) = d_fit;
			fn = fieldnames(prop_struct);
			obj_y_arr = [];
			for i=1:length(fn)
				indx_arr = (prop_struct.(fn{i}).indx_arr(1)):1:(prop_struct.(fn{i}).indx_arr(2));
				[Ts,Tp,Rs,Rp,phi_Rs,phi_Rp,phi_Ts,phi_Tp,GDD_Rs,GDD_Rp,GDD_Ts,GDD_Tp] = comp_sub_and_stacks(app,d,mat_fn,indx_arr);
				switch prop_struct.(fn{i}).prop
					case 'Ts'
						next_arr = Ts'*100;
					case 'Tp'
						next_arr = Tp'*100;
					case 'Rs'
						next_arr = Rs'*100;
					case 'Rp'
						next_arr = Rp'*100;
					case 'Ts_GDD'
						next_arr = GDD_Ts';
					case 'Tp_GDD'
						next_arr = GDD_Tp';
					case 'Rs_GDD'
						next_arr = GDD_Rs';
					case 'Rp_GDD'
						next_arr = GDD_Rp';
				end
				obj_y_arr = [obj_y_arr;(next_arr*prop_struct.(fn{i}).weight)];
			end
		end
		
		function stop = fitUpdatePlot(app,d_init_all,fit_indx,d_fit)
			d = d_init_all;
			d(fit_indx) = d_fit;
			app.StackDesignTable.Data(:,2) = num2cell(d');
			updatePlot(app);
			drawnow;
			stop = app.StopFittingButton.Value;
		end

		function model_fitting(app)
			d_init_all = cell2mat(app.StackDesignTable.Data(:,2))';
			for i=1:numel(d_init_all)
				mat_fn{i} = app.StackDesignTable.Data{i,1};
			end
			fit_indx = app.selected_layers';
			nearest_index = @(axis,axis_value) find(abs(axis-axis_value) == min(abs(axis-axis_value)),1);
			for i=1:size(app.FitTargetsTable.Data,1)
				prop_struct.(sprintf('prop%i',i)).prop = app.FitTargetsTable.Data{i,1};
				if contains(app.FitTargetsTable.Data{i,1},'GDD')
					prop_struct.(sprintf('prop%i',i)).val = app.FitTargetsTable.Data{i,2};
				else
					prop_struct.(sprintf('prop%i',i)).val = app.FitTargetsTable.Data{i,2};
				end
				prop_struct.(sprintf('prop%i',i)).indx_arr = [nearest_index(app.wl,app.FitTargetsTable.Data{i,3}),nearest_index(app.wl,app.FitTargetsTable.Data{i,4})];
				prop_struct.(sprintf('prop%i',i)).weight = app.FitTargetsTable.Data{i,5};
			end
			fun = @(d_fit,xdata) obj_fun(app,d_init_all,d_fit,fit_indx,mat_fn,xdata,prop_struct);
			outfun = @(d_fit,optimValues,state) fitUpdatePlot(app,d_init_all,fit_indx,d_fit);
			
			% make target
				[target_x_arr,target_y_arr] = makeFitTarget(app,prop_struct);
			% make fitting problem
				problem.objective = fun;
				problem.x0 = d_init_all(fit_indx);
				problem.xdata = target_x_arr;
				problem.ydata = target_y_arr;
				problem.solver = 'lsqcurvefit';
				problem.options = optimoptions(@lsqcurvefit,'Algorithm','levenberg-marquardt','MaxFunctionEvaluations',1000,'Display','iter-detailed','OutputFcn',outfun);
			% fit problem
				[d_fit,resnorm,residual,exitflag,output,lambda,jacobian] = lsqcurvefit(problem);
				d = d_init_all;
				d(fit_indx) = d_fit;
				app.StackDesignTable.Data(:,3) = num2cell(zeros(size(app.StackDesignTable.Data(:,3))));
				app.StackDesignTable.Data(:,2) = num2cell(d');
				updatePlot(app);
				if app.StopFittingButton.Value == 1
					app.FeedbackBox.Value = 'Fitting stopped by user.';
					app.StopFittingButton.Value = 0;
				end
		end
        function initMaterialFactorsTable(app)
			try
				fn = fieldnames(app.orig_mat);
				tbl = [];
				for i=1:length(fn)
					tbl = [tbl;{fn{i},1,1}];
				end
				set(app.MaterialFactorsTable,'ColumnFormat',{[],[],[]},'Data',tbl);
			catch exception
				update_feedback_box(app,exception);
			end
        end
		function T = sub_T(app,indx_arr,mat_fn_sub,mat_fn_init,d)
			for k=1:numel(indx_arr)
				n_init = app.mat.(mat_fn_init).n(indx_arr(k));
				n = app.mat.(mat_fn_sub).n(indx_arr(k));
				ncos = sqrt((n')^2-real(n_init)^2*sin(app.AOI/180*pi)^2);
				phi = (2*pi/app.wl(indx_arr(k)))*d*ncos;
				T(k) = exp(2*imag(phi));
				if T(k) > 1
					T(k) = 1;
				end
			end
        end
        function [Ts,Tp,Rs,Rp,phi_Rs,phi_Rp,phi_Ts,phi_Tp,GDD_Rs,GDD_Rp,GDD_Ts,GDD_Tp] = comp_sub_and_stacks(app,d,mat_fn,wl_index_arr)
            inc_index = 1;
			sub_index = find(strcmp('substrate',app.StackDesignTable.RowName));
			exit_index = length(mat_fn);
			% front surface
				front_d = d(inc_index:sub_index)';
				for i=inc_index:sub_index
					front_mat_fn{i} = mat_fn{i};
				end
				front_stack = thinFilmStack(app,front_d,front_mat_fn,wl_index_arr,mat_fn{1});
			% back surface
				back_d = d(sub_index:exit_index)';
				for i=sub_index:exit_index
					back_mat_fn{i-sub_index+1} = mat_fn{i};
				end
				back_stack = thinFilmStack(app,back_d,back_mat_fn,wl_index_arr,mat_fn{1});
			% compute transmission through substrate after substrate absorption
				T = sub_T(app,wl_index_arr,mat_fn{sub_index},mat_fn{1},d(sub_index));
			% compute the composite transmission, reflection, phase and GDD
				if app.NoneButton.Value
					Ts = (front_stack.Ts).*(back_stack.Ts).*T;
					Tp = (front_stack.Tp).*(back_stack.Tp).*T;
					Rs = (front_stack.Rs);
					Rp = (front_stack.Rp);
				else
					Ts = (front_stack.Ts).*(back_stack.Ts).*T./(1-(front_stack.Rs).*(back_stack.Rs));
					Tp = (front_stack.Tp).*(back_stack.Tp).*T./(1-(front_stack.Rp).*(back_stack.Rp));
					Rs = (front_stack.Rs) + (front_stack.Ts).*(front_stack.Ts).*(back_stack.Rs).*(T.^2)./(1-(front_stack.Rs).*(back_stack.Rs).*(T.^2));
					Rp = (front_stack.Rp) + (front_stack.Tp).*(front_stack.Tp).*(back_stack.Rp).*(T.^2)./(1-(front_stack.Rp).*(back_stack.Rp).*(T.^2));
				end
				phi_Rs = front_stack.phi_Rs;
				phi_Rp = front_stack.phi_Rp;
				phi_Ts = front_stack.phi_Ts+back_stack.phi_Ts;
				phi_Tp = front_stack.phi_Tp+back_stack.phi_Tp;
				GDD_Rs = front_stack.GDD_Rs;
				GDD_Rp = front_stack.GDD_Rp;
				GDD_Ts = front_stack.GDD_Ts+back_stack.GDD_Ts;
				GDD_Tp = front_stack.GDD_Tp+back_stack.GDD_Tp;
        end
    end

    
    methods (Access = private)
        
        
    end
    

	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			try
				loadMaterials(app);
				initMaterialFactorsTable(app);
				cell_arr = app.MaterialFactorsTable.Data(:,1);
				% empirical adjustment for Nickel on e-beam
					app.MaterialFactorsTable.Data{strcmp(cell_arr,'Ni'),3} = 1.35;
				initDesignTable(app);
				initFitTargetTable(app);
				app.AOI = app.AOIdegreesSpinner.Value;
				format("shortG");
				try
					app.wl = table2array(readtable('wl 0p02 750-800nm.txt'));
					updateMaterials(app);
					checkStackDesignTable(app);
					updatePlot(app);
				catch exception1
					update_feedback_box(app,exception1);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: LoadAxisButton
		function LoadAxisButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.txt','Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					app.wl = table2array(readtable([path,file]));
				end
				updateMaterials(app);
				checkStackDesignTable(app);
				updatePlot(app);
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: ClearFeedbackButton
		function ClearFeedbackButtonPushed(app, event)
            app.FeedbackBox.Value = '';
		end

		% Value changed function: AOIdegreesSpinner
		function AOIdegreesSpinnerValueChanged(app, event)
            app.AOI = app.AOIdegreesSpinner.Value;
			updatePlot(app);
		end

		% Button pushed function: LoadDesignButton
		function LoadDesignButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.csv','Select a File',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					try
						tbl = readtable([path,file],'ReadRowNames',true,'PreserveVariableNames',true);
					catch
						return
					end
					app.FeedbackBox.Value = '';
					if isempty(tbl)
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Table is empty.';
					elseif size(tbl,2) ~= 5
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Table must have 6 columns.';
					elseif ~all(strcmp(tbl.Properties.VariableNames,{'Material','Thickness (nm)','Auto Thickness','Auto Wavelength (nm)','Auto Fraction'}))
						app.FeedbackBox.Value = 'ERROR: Loading design failed. First 6 columns of .csv file must be: Row | Material | Thickness (nm) | Auto Thickness | Auto Wavelength (nm) | Auto Fraction ';
					elseif size(tbl,1) < 3
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Must have 3 or more layers.';
					elseif ~any(contains(tbl.Properties.RowNames,'incident')) || sum(contains(tbl.Properties.RowNames,'incident')) > 1
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Missing "incident" layer rown name.';
					elseif ~any(contains(tbl.Properties.RowNames,'substrate')) || sum(contains(tbl.Properties.RowNames,'substrate')) > 1
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Missing "substrate" layer rown name.';
					elseif ~any(contains(tbl.Properties.RowNames,'exit')) || sum(contains(tbl.Properties.RowNames,'exit')) > 1
						app.FeedbackBox.Value = 'ERROR: Loading design failed. Missing "exit" layer rown name.';
					else
						inc_index = find(contains(tbl.Properties.RowNames,'incident'));
						sub_index = find(contains(tbl.Properties.RowNames,'substrate'));
						exit_index = find(contains(tbl.Properties.RowNames,'exit'));
						if inc_index >= sub_index || sub_index >= exit_index
							app.FeedbackBox.Value = 'ERROR: Loading design failed. Must have incident, substrate and exit layers in order.';
						else
							app.StackDesignTable.Data = [];
							app.StackDesignTable.Data = table2cell(tbl);
							app.StackDesignTable.RowName = tbl.Properties.RowNames;
							if sub_index > 2
								app.front_layers = 2:(sub_index-1);
							else
								app.front_layers = [];
							end
							if exit_index > (sub_index+1)
								app.back_layers = (sub_index+1):(exit_index-1);
							else
								app.back_layers = [];
							end
							app.selected_layers = [];
							app.front_layers_selected = [];
							app.back_layers_selected = [];
							app.inc_selected = 0;
							app.sub_selected = 0;
							app.exit_selected = 0;
						% check for AOI
							file_AOI = string(extractBetween(file,'AOI','.csv'));
							if ~isempty(file_AOI)
								app.AOI = sscanf(file_AOI,'%i');
								app.AOIdegreesSpinner.Value = app.AOI;
							end
						% update plot
							updateMaterials(app);
							checkStackDesignTable(app);
							updatePlot(app);
						end
					end
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: SaveDesignButton
		function SaveDesignButtonPushed(app, event)
			try
				mat = app.StackDesignTable.Data(:,1);
				sub_indx = find(strcmp(app.StackDesignTable.RowName,'substrate'));
				sub_mat = mat{sub_indx};
				rm_indx = sub_indx;
				if strcmp(mat(sub_indx),mat(sub_indx-1))
					rm_indx = [rm_indx,sub_indx-1];
				end
				if strcmp(mat(sub_indx),mat(sub_indx+1))
					rm_indx = [rm_indx,sub_indx+1];
				end
				mat(rm_indx) = [];
				mat = unique(mat);
				rm_indx = strcmp(mat,'Air');
				mat(find(rm_indx)) = [];
				str = [];
				if ~isempty(mat)
					for i=1:size(mat,1)
						str = [str,mat{i},' '];
					end
					str = [str,'on '];
				end
				file_name = [str,char(sub_mat),' AOI ',char(string(app.AOI)),'.csv'];
				[file,path] = uiputfile('*.csv','Save File',[app.last_path,file_name]);
				figure(app.UIFigure);
				tbl = cell2table(app.StackDesignTable.Data,'VariableNames',app.StackDesignTable.ColumnName,'RowNames',string(app.StackDesignTable.RowName));
				writetable(tbl,[path,file],'WriteRowNames',true);
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Cell edit callback: StackDesignTable
		function StackDesignTableCellEdit(app, event)
			try
				indices = event.Indices;
				newData = event.NewData;
				checkStackDesignTable(app);
				if app.AutoPlotButton.Value
					updatePlot(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: AddLayerButton
		function AddLayerButtonPushed(app, event)
			try
				t = app.StackDesignTable.Data;
				exit_row = size(t,1);
				sub_index = find(strcmp('substrate',app.StackDesignTable.RowName));
				if ~isempty(app.front_layers_selected)
					above = t(1:(app.front_layers_selected(1)-1),:);
					add = t(app.front_layers_selected,:);
					below = t(app.front_layers_selected(1):exit_row,:);
					t = [above;add;below];
					app.front_layers = 2:(numel(app.front_layers)+1+size(add,1));
					app.back_layers = app.back_layers+size(add,1);
					app.front_layers_selected = [];
					app.back_layers_selected = [];
					app.StackDesignTable.Data = t;
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
					checkStackDesignTable(app);
					updatePlot(app);
				elseif ~isempty(app.back_layers_selected)
					above = t(1:(app.back_layers_selected(1)-1),:);
					add = t(app.back_layers_selected,:);
					below = t(app.back_layers_selected(1):exit_row,:);
					t = [above;add;below];
					app.back_layers = (sub_index+1):(sub_index+numel(app.back_layers)+size(add,1));
					app.front_layers_selected = [];
					app.back_layers_selected = [];
					app.StackDesignTable.Data = t;
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
					checkStackDesignTable(app);
					updatePlot(app);
				elseif app.inc_selected
					above = t(1,:);
					add = t(1,:);
					add{1,2} = 0;
					below = t(2:exit_row,:);
					t = [above;add;below];
					app.front_layers = 2:(numel(app.front_layers)+1+size(add,1));
					app.back_layers = app.back_layers+size(add,1);
					app.front_layers_selected = [];
					app.back_layers_selected = [];
					app.StackDesignTable.Data = t;
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
					checkStackDesignTable(app);
					updatePlot(app);
				elseif app.sub_selected
					above = t(1:sub_index,:);
					add = t(sub_index,:);
					add{1,2} = 0;
					below = t((sub_index+1):exit_row,:);
					t = [above;add;below];
					app.back_layers = (sub_index+1):(sub_index+numel(app.back_layers)+size(add,1));
					app.front_layers_selected = [];
					app.back_layers_selected = [];
					app.StackDesignTable.Data = t;
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
					checkStackDesignTable(app);
					updatePlot(app);
				elseif app.exit_selected
					above = t(1:(exit_row-1),:);
					add = t(exit_row,:);
					add{1,2} = 0;
					below = t(exit_row,:);
					t = [above;add;below];
					app.back_layers = (sub_index+1):(sub_index+numel(app.back_layers)+size(add,1));
					app.front_layers_selected = [];
					app.back_layers_selected = [];
					app.StackDesignTable.Data = t;
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
					checkStackDesignTable(app);
					updatePlot(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: RemoveLayerButton
		function RemoveLayerButtonPushed(app, event)
			try
				if ~isempty(app.selected_layers)
					app.StackDesignTable.Data(app.selected_layers,:) = [];
					app.StackDesignTable.RowName(app.selected_layers) = [];
					sub_index = find(strcmp('substrate',app.StackDesignTable.RowName));
					if sub_index > 2
						app.front_layers = 2:(sub_index-1);
					else
						app.front_layers = [];
					end
					if  sub_index < (size(app.StackDesignTable.Data,1)-1)
						app.back_layers = (sub_index+1):(size(app.StackDesignTable.Data,1)-1);
					else
						app.back_layers = [];
					end
					app.StackDesignTable.RowName = ["incident",string(app.front_layers-1),"substrate",string(app.back_layers-2),"exit"];
				end
				app.selected_layers = [];
				app.front_layers_selected = [];
				app.back_layers_selected = [];
				checkStackDesignTable(app);
				updatePlot(app);
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Cell selection callback: StackDesignTable
		function StackDesignTableCellSelection(app, event)
			try
				app.selected_layers = event.Indices(:,1);
				sub_index = find(strcmp('substrate',app.StackDesignTable.RowName));
				exit_index = size(app.StackDesignTable.Data,1);
				temp = find(app.selected_layers == 1);
				if ~isempty(temp)
					app.inc_selected = 1;
					app.selected_layers(temp) = [];
				else
					app.inc_selected = 0;
				end
				temp = find(app.selected_layers == sub_index);
				if ~isempty(temp)
					app.sub_selected = 1;
					app.selected_layers(temp) = [];
				else
					app.sub_selected = 0;
				end
				temp = find(app.selected_layers == exit_index);
				if ~isempty(temp)
					app.exit_selected = 1;
					app.selected_layers(temp) = [];
				else
					app.exit_selected = 0;
				end
				if sub_index > 2
					temp = find(app.selected_layers < sub_index);
					if ~isempty(temp)
						app.front_layers_selected = app.selected_layers(temp);
					else
						app.front_layers_selected = [];
					end
				else
					app.front_layers_selected = [];
				end
				if exit_index > (sub_index+1)
					temp = find(app.selected_layers > sub_index);
					if ~isempty(temp)
						app.back_layers_selected = app.selected_layers(temp);
					else
						app.back_layers_selected = [];
					end
				else
					app.back_layers_selected = [];
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: StartFittingButton
		function StartFittingButtonPushed(app, event)
			try
				app.FeedbackBox.Value = 'Model fitting in progress...';
				model_fitting(app);
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: UpdatePlotButton
		function UpdatePlotButtonPushed(app, event)
            updatePlot(app);
		end

		% Button pushed function: AddTargetButton
		function AddTargetButtonPushed(app, event)
			try
				t = app.FitTargetsTable.Data;
				last_row = size(t,1);
				if isempty(app.targets_selected)
					if last_row == 0
						nr = {'Rs',99.9,1850,2000};
					else
						nr = t(last_row,:);
					end
					t = [t;nr];
				else
					if app.targets_selected(1) > 1
						above = t(1:(app.targets_selected(1)-1),:);
					else
						above = [];
					end
					add = t(app.targets_selected,:);
					if app.targets_selected(numel(app.targets_selected)) <= last_row
						below = t(app.targets_selected(1):last_row,:);
					else
						below = [];
					end
					t = [above;add;below];
				end
				app.FitTargetsTable.Data = t;
				app.FitTargetsTable.RowName = 1:1:(size(app.FitTargetsTable.Data,1));
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: RemoveTargetButton
		function RemoveTargetButtonPushed(app, event)
			try
				if ~isempty(app.targets_selected)
					if size(app.FitTargetsTable.Data,1) > 1
						app.FitTargetsTable.Data(app.targets_selected,:) = [];
						app.FitTargetsTable.RowName = 1:1:(size(app.FitTargetsTable.Data,1));
					end
				end
				app.targets_selected = [];
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Cell selection callback: FitTargetsTable
		function FitTargetsTableCellSelection(app, event)
			try
				temp = event.Indices;
				app.targets_selected = temp(:,1);
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Cell edit callback: MaterialFactorsTable
		function MaterialFactorsTableCellEdit(app, event)
            updateMaterials(app);
			if app.AutoPlotButton.Value
				updatePlot(app);
			end
		end

		% Value changed function: PlotUnitsDropDown
		function PlotUnitsDropDownValueChanged(app, event)
            updatePlot(app);
		end

		% Selection changed function: InternalReflectionsButtonGroup
		function InternalReflectionsButtonGroupSelectionChanged(app, event)
            updatePlot(app);
		end

		% Value changed function: nmEditField
		function nmEditFieldValueChanged(app, event)
            app.cm1EditField.Value = double(uint64(1e7/(app.nmEditField.Value)));
		end

		% Value changed function: cm1EditField
		function cm1EditFieldValueChanged(app, event)
            app.nmEditField.Value = double(uint64(1e7/(app.cm1EditField.Value)));
		end

		% Close request function: UIFigure
		function UIFigureCloseRequest(app, event)
            close(app.fig);
			delete(app);
		end

		% Button pushed function: LoadppolTransmissionButton
		function LoadppolTransmissionButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.mat','Select a File (p-pol)',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					try
						data = load([path,file]);
					catch
						return
					end	
					if isfield(data,'TR')
						app.data_p = data.TR;
						app.data_p(:,1) = (1e7)./(app.data_p(:,1)); % convert to nm
					else
						app.FeedbackBox.Value = 'Error loading data: TR is not a field of data.';
					end
					app.FeedbackBox.Value = '';
					updatePlot(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: LoadspolTransmissionButton
		function LoadspolTransmissionButtonPushed(app, event)
			try
				[file,path] = uigetfile('*.mat','Select a File (s-pol)',[app.last_path]);
				figure(app.UIFigure);
				if file ~= 0
					app.last_path = path;
					try
						data = load([path,file]);
					catch
						return
					end	
					if isfield(data,'TR')
						app.data_s = data.TR;
						app.data_s(:,1) = (1e7)./(app.data_s(:,1)); % convert to nm
					else
						app.FeedbackBox.Value = 'Error loading data: TR is not a field of data.';
					end
					app.FeedbackBox.Value = '';
					updatePlot(app);
				end
			catch exception
				update_feedback_box(app,exception);
			end
		end

		% Button pushed function: UnloadTransmissionButton
		function UnloadTransmissionButtonPushed(app, event)
            app.data_p = [];
			app.data_s = [];
			updatePlot(app);
		end

		% Button pushed function: CopyModeltoDataButton
		function CopyModeltoDataButtonPushed(app, event)
			indx_Ts = find(strcmp({app.fig.Children(1).Children(12).Children.DisplayName},'T_s'));
			indx_Tp = find(strcmp({app.fig.Children(1).Children(12).Children.DisplayName},'T_p'));
			app.data_p = [(1e7)./(app.fig.Children(1).Children(12).Children(indx_Tp).XData);app.fig.Children(1).Children(12).Children(indx_Tp).YData/100]';
			app.data_s = [(1e7)./(app.fig.Children(1).Children(12).Children(indx_Ts).XData);app.fig.Children(1).Children(12).Children(indx_Ts).YData/100]';
			updatePlot(app);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
			app.StackDesignTable = uitable(app.UIFigure);
			app.StackDesignTable.ColumnName = {'Material'; 'Thickness (nm)'; 'Auto Thickness'; 'Auto Wavelength (nm)'; 'Auto Fraction'};
			app.StackDesignTable.RowName = {};
			app.StackDesignTable.ColumnEditable = true;
			app.StackDesignTable.CellEditCallback = createCallbackFcn(app, @StackDesignTableCellEdit, true);
			app.StackDesignTable.CellSelectionCallback = createCallbackFcn(app, @StackDesignTableCellSelection, true);
			app.AOIdegreesSpinnerLabel = uilabel(app.UIFigure);
			app.AOIdegreesSpinnerLabel.Text = 'AOI (degrees)';
			app.AOIdegreesSpinner = uispinner(app.UIFigure);
			app.AOIdegreesSpinner.Limits = [0 90];
			app.AOIdegreesSpinner.ValueChangedFcn = createCallbackFcn(app, @AOIdegreesSpinnerValueChanged, true);
			app.AOIdegreesSpinner.Value = 45;
			app.AddLayerButton = uibutton(app.UIFigure, 'push');
			app.AddLayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddLayerButtonPushed, true);
			app.AddLayerButton.Text = 'Add Layer';
			app.RemoveLayerButton = uibutton(app.UIFigure, 'push');
			app.RemoveLayerButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLayerButtonPushed, true);
			app.RemoveLayerButton.Text = 'Remove Layer';
			app.SaveDesignButton = uibutton(app.UIFigure, 'push');
			app.SaveDesignButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDesignButtonPushed, true);
			app.SaveDesignButton.Text = 'Save Design';
			app.LoadDesignButton = uibutton(app.UIFigure, 'push');
			app.LoadDesignButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDesignButtonPushed, true);
			app.LoadDesignButton.Text = 'Load Design';
			app.LoadAxisButton = uibutton(app.UIFigure, 'push');
			app.LoadAxisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAxisButtonPushed, true);
			app.LoadAxisButton.Text = 'Load Axis';
			app.FeedbackBox = uieditfield(app.UIFigure, 'text');
			app.ClearFeedbackButton = uibutton(app.UIFigure, 'push');
			app.ClearFeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFeedbackButtonPushed, true);
			app.ClearFeedbackButton.Text = {'Clear'; 'Feedback'};
			app.UpdatePlotButton = uibutton(app.UIFigure, 'push');
			app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
			app.UpdatePlotButton.Text = 'Update Plot';
			app.FittingPanel = uipanel(app.UIFigure);
			app.FittingPanel.Title = 'Fitting';
			app.StartFittingButton = uibutton(app.FittingPanel, 'push');
			app.StartFittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartFittingButtonPushed, true);
			app.StartFittingButton.Text = 'Start Fitting';
			app.StopFittingButton = uibutton(app.FittingPanel, 'state');
			app.StopFittingButton.Text = 'Stop Fitting';
			app.FitTargetsTable = uitable(app.UIFigure);
			app.FitTargetsTable.ColumnName = {'Property'; 'Target (% or fs^2)'; 'Min. (nm)'; 'Max. (nm)'; 'Weight'};
			app.FitTargetsTable.RowName = {};
			app.FitTargetsTable.ColumnEditable = true;
			app.FitTargetsTable.CellSelectionCallback = createCallbackFcn(app, @FitTargetsTableCellSelection, true);
			app.AddTargetButton = uibutton(app.UIFigure, 'push');
			app.AddTargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddTargetButtonPushed, true);
			app.AddTargetButton.Text = 'Add Target';
			app.RemoveTargetButton = uibutton(app.UIFigure, 'push');
			app.RemoveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTargetButtonPushed, true);
			app.RemoveTargetButton.Text = 'Remove Target';
			app.MaterialFactorsTable = uitable(app.UIFigure);
			app.MaterialFactorsTable.ColumnName = {'Material'; 'Index Factor'; 'Thickness Factor'};
			app.MaterialFactorsTable.RowName = {};
			app.MaterialFactorsTable.ColumnEditable = [false true true];
			app.MaterialFactorsTable.CellEditCallback = createCallbackFcn(app, @MaterialFactorsTableCellEdit, true);
			app.PlotUnitsDropDownLabel = uilabel(app.UIFigure);
			app.PlotUnitsDropDownLabel.Text = 'Plot Units';
			app.PlotUnitsDropDown = uidropdown(app.UIFigure);
			app.PlotUnitsDropDown.Items = {'cm-1', 'nm', 'um'};
			app.PlotUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotUnitsDropDownValueChanged, true);
			app.PlotUnitsDropDown.Value = 'cm-1';
			app.InternalReflectionsButtonGroup = uibuttongroup(app.UIFigure);
			app.InternalReflectionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InternalReflectionsButtonGroupSelectionChanged, true);
			app.InternalReflectionsButtonGroup.Title = 'Internal Reflections';
			app.NoneButton = uitogglebutton(app.InternalReflectionsButtonGroup);
			app.NoneButton.Text = 'None';
			app.NoneButton.Value = true;
			app.MultipleButton = uitogglebutton(app.InternalReflectionsButtonGroup);
			app.MultipleButton.Text = 'Multiple';
			app.ConversionsPanel = uipanel(app.UIFigure);
			app.ConversionsPanel.Title = 'Conversions';
			app.nmEditFieldLabel = uilabel(app.ConversionsPanel);
			app.nmEditFieldLabel.Text = 'nm';
			app.nmEditField = uieditfield(app.ConversionsPanel, 'numeric');
			app.nmEditField.ValueDisplayFormat = '%.0f';
			app.nmEditField.ValueChangedFcn = createCallbackFcn(app, @nmEditFieldValueChanged, true);
			app.nmEditField.Value = 4630;
			app.cm1EditFieldLabel = uilabel(app.ConversionsPanel);
			app.cm1EditFieldLabel.Text = 'cm-1';
			app.cm1EditField = uieditfield(app.ConversionsPanel, 'numeric');
			app.cm1EditField.ValueDisplayFormat = '%.0f';
			app.cm1EditField.ValueChangedFcn = createCallbackFcn(app, @cm1EditFieldValueChanged, true);
			app.cm1EditField.Value = 2160;
			app.LoadppolTransmissionButton = uibutton(app.UIFigure, 'push');
			app.LoadppolTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadppolTransmissionButtonPushed, true);
			app.LoadppolTransmissionButton.Text = {'Load p-pol'; 'Transmission'};
			app.LoadspolTransmissionButton = uibutton(app.UIFigure, 'push');
			app.LoadspolTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadspolTransmissionButtonPushed, true);
			app.LoadspolTransmissionButton.Text = {'Load s-pol'; 'Transmission'};
			app.AutoPlotButton = uibutton(app.UIFigure, 'state');
			app.AutoPlotButton.Text = 'Auto-Plot';
			app.UnloadTransmissionButton = uibutton(app.UIFigure, 'push');
			app.UnloadTransmissionButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadTransmissionButtonPushed, true);
			app.UnloadTransmissionButton.Text = {'Unload'; 'Transmission'};
			app.CopyModeltoDataButton = uibutton(app.UIFigure, 'push');
			app.CopyModeltoDataButton.ButtonPushedFcn = createCallbackFcn(app, @CopyModeltoDataButtonPushed, true);
			app.CopyModeltoDataButton.Text = {'Copy Model'; 'to Data'};
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = thin_film_simulator

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end